classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Figure              matlab.ui.Figure
        Menu                matlab.ui.container.Menu
        Grid                matlab.ui.container.GridLayout
        TabGroup            matlab.ui.container.TabGroup
        TabResize           matlab.ui.container.Tab
        TabSeg              matlab.ui.container.Tab
        GridSeg             matlab.ui.container.GridLayout
        GridSegPanel        matlab.ui.container.GridLayout
        LabelTimeSeg        matlab.ui.control.Label
        ButtonSavePng       matlab.ui.control.Button
        ButtonSaveCur       matlab.ui.control.Button
        ButtonSaveAll       matlab.ui.control.Button
        ButtonQuality       matlab.ui.control.Button
        TextAreaQuality     matlab.ui.control.TextArea
        LabelLoadSeg        matlab.ui.control.Label
        ButtonLoadSeg       matlab.ui.control.Button
        LabelChoose         matlab.ui.control.Label
        ListBoxChooseLayer  matlab.ui.control.ListBox
        Label               matlab.ui.control.Label
        ButtonStartSeg      matlab.ui.control.Button
        LabelLoadLabel      matlab.ui.control.Label
        ButtonLoadLabel     matlab.ui.control.Button
        LabelLoadModel      matlab.ui.control.Label
        ButtonLoadModel     matlab.ui.control.Button
        LabelLoadMap        matlab.ui.control.Label
        ButtonLoadMap       matlab.ui.control.Button
        AxesMap             matlab.ui.control.UIAxes
        AxesSeg             matlab.ui.control.UIAxes
        TabLoc              matlab.ui.container.Tab
        TabRec              matlab.ui.container.Tab
        TabRecLO            matlab.ui.container.Tab
        TabRecSqO           matlab.ui.container.Tab
        TabComplex          matlab.ui.container.Tab
        TabDEM              matlab.ui.container.Tab
    end

    
    properties (Access = private)
        % Этап масштабирования
        imageCut;       % Оригинальное изображение (Width * Height * 3, uint8)
        imageMap;       % Изображение карты (Width * Height * 3, uint8)

        % Этап сегментации
        modelSeg;       % Модель
        labelSeg;       % Разметочный файл (Width * Height * 3, uint8)
        colorSeg;       % Таблица соответствия классов и цветов разметки
        outputSeg;      % Выход модели (Width * Height * Layers, uint8)
        imageSeg;       % Изображение слоя (Width * Height, uint8)
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath(pwd + "\lib");
            app.colorSeg = load("D:\Desktop\0 Диссер\code\vol4git\data\colormap_seg.mat").colorMap;
        end

        % Button pushed function: ButtonLoadMap
        function ButtonLoadMapPushed(app, event)
            [file, path] = uigetfile({'*.png;*.jpeg','Image files'}, 'Select a file', pwd + "\data\map");
            app.LabelLoadMap.Text = fullfile(path, file);
            app.imageMap = imread(fullfile(path, file));
            image(app.AxesMap, app.imageMap, 'HitTest', 'off');
            daspect(app.AxesMap, [1 1 1]);
            zoom(app.AxesMap, 'on');
            app.AxesMap.XLim = [1, min(size(app.imageMap, [1, 2]))];
            app.AxesMap.YLim = [1, min(size(app.imageMap, 1), 1.5 * size(app.imageMap, 2))];
            app.AxesMap.UserData.linkprop = linkprop([app.AxesMap, app.AxesSeg], {'XLim', 'YLim'});
        end

        % Button down function: AxesMap
        function AxesMapButtonDown(app, event)
            zoom(app.AxesMap, 'on');
        end

        % Button pushed function: ButtonLoadModel
        function ButtonLoadModelPushed(app, event)
            [file, path] = uigetfile({'*.mat','Matlab files'}, 'Select a file', pwd + "\models\seg");
            app.LabelLoadModel.Text = fullfile(path, file);
            app.modelSeg = load(fullfile(path, file)).model;
        end

        % Button down function: AxesSeg
        function AxesSegButtonDown(app, event)
            zoom(app.AxesSeg, 'on');
        end

        % Button pushed function: ButtonLoadSeg
        function ButtonLoadSegPushed(app, event)
            [file, path] = uigetfile({'*.png;*.jpeg','Image files'; '*.mat', 'Matlab files'}, ...
                                     'Select a file', pwd + "\data\seg");
            app.LabelLoadSeg.Text = fullfile(path, file);
            [~, ~, ext] = fileparts(fullfile(path, file));
            if string(ext) == ".png" || string(ext) == ".jpeg"
                app.imageSeg = imread(fullfile(path, file));
                app.outputSeg = app.imageSeg;
                app.ListBoxChooseLayer.Items = "Загруженное изображение";
                app.ListBoxChooseLayer.Value = app.ListBoxChooseLayer.Items(1);
            elseif string(ext) == ".mat"
                app.outputSeg = load(fullfile(path, file)).seg;
                app.imageSeg = app.outputSeg(:, :, 1);
                app.ListBoxChooseLayer.Items = string(1 : size(app.outputSeg, 3)) + "-й слой";
                app.ListBoxChooseLayer.Value = app.ListBoxChooseLayer.Items(1);
            end
            image(app.AxesSeg, app.imageSeg, 'HitTest', 'off');
            daspect(app.AxesSeg, [1 1 1]);
            zoom(app.AxesSeg, 'on');
            app.AxesSeg.XLim = [1, min(size(app.imageSeg, [1, 2]))];
            app.AxesSeg.YLim = [1, min(size(app.imageSeg, 1), 1.5 * size(app.imageSeg, 2))];
            % app.AxesSeg.UserData.linkprop = linkprop([app.AxesMap, app.AxesSeg], {'XLim', 'YLim'});
        end

        % Button pushed function: ButtonStartSeg
        function ButtonStartSegPushed(app, event)
            if ~exist('app.modelSeg', 'var')
                app.modelSeg = load(pwd + "\models\seg\net_5_41.mat").model;
                app.LabelLoadModel.Text = pwd + "\models\seg\net_5_41.mat";
            end
            tic
            app.outputSeg = segmentImage(app.imageMap, app.modelSeg);
            tt = toc;
            app.ListBoxChooseLayer.Items = string(1 : size(app.outputSeg, 3));
            app.ListBoxChooseLayer.Value = "1";
            app.imageSeg = app.outputSeg(:, :, 1);
            image(app.AxesSeg, app.imageSeg, 'HitTest', 'off');
            daspect(app.AxesSeg, [1 1 1]);
            zoom(app.AxesSeg, 'on');
            app.AxesSeg.XLim = [1, min(size(app.imageSeg, [1, 2]))];
            app.AxesSeg.YLim = [1, min(size(app.imageSeg, 1), 1.5 * size(app.imageSeg, 2))];
            app.LabelTimeSeg.Text = "Время обработки: " + string(tt) + " с" + newline +...
                                    "Скорость обработки: " + string(tt / numel(app.outputSeg(:, :, 1)) * 1e6) + " c/Мп";
        end

        % Value changed function: ListBoxChooseLayer
        function ListBoxChooseLayerValueChanged(app, event)
            layer = app.ListBoxChooseLayer.Value;
            layer = split(layer, '-');
            layer = str2double(layer(1));
            image(app.AxesSeg, app.outputSeg(:, :, layer), 'HitTest', 'off');
        end

        % Button pushed function: ButtonSaveAll
        function ButtonSaveAllPushed(app, event)
            [file,path] = uiputfile({'*.mat', 'Matlab files'}, 'Select a file', pwd + "\data\seg");
            seg = app.outputSeg;
            save(fullfile(path, file), 'seg');
        end

        % Button pushed function: ButtonSaveCur
        function ButtonSaveCurPushed(app, event)
            [file,path] = uiputfile({'*.mat', 'Matlab files'}, 'Select a file', pwd + "\data\seg");
            layer = app.ListBoxChooseLayer.Value;
            layer = split(layer, '-');
            layer = str2double(layer(1));
            seg = app.outputSeg(:, :, layer);
            save(fullfile(path, file), 'seg');
        end

        % Button pushed function: ButtonSavePng
        function ButtonSavePngPushed(app, event)
            [file,path] = uiputfile({'*.png','Image files'}, 'Select a file', pwd + "\data\seg");
            layer = app.ListBoxChooseLayer.Value;
            layer = split(layer, '-');
            layer = str2double(layer(1));
            seg = app.outputSeg(:, :, layer);
            imwrite(seg, fullfile(path, file));
        end

        % Button pushed function: ButtonLoadLabel
        function ButtonLoadLabelPushed(app, event)
            [file, path] = uigetfile({'*.png;*.jpeg','Image files'; '*.mat', 'Matlab files'}, ...
                                     'Select a label file', pwd + "\data\labeled segments");
            app.LabelLoadLabel.Text = fullfile(path, file);
            app.labelSeg = imread(fullfile(path, file));
        end

        % Button pushed function: ButtonQuality
        function ButtonQualityPushed(app, event)
            [~, file, ~] = fileparts(app.LabelLoadSeg.Text);
            if size(app.outputSeg, 3) == 1
                temp = char(file);
                if temp(end) == 'b'
                    layer = 2;
                elseif temp(end) == 'k'
                    layer = 1;
                end
                app.outputSeg(:, :, layer) = app.imageSeg;
                app.outputSeg(:, :, setdiff(1 : size(app.colorSeg, 1), layer)) = 0;
            end
            f1 = getSegQuality(app.outputSeg, app.labelSeg, app.colorSeg);
            app.TextAreaQuality.Value = strjoin("f1(" + string(1 : size(app.colorSeg, 1))' + ...
                                                "-й класс) = " + string(f1), newline);
            if all(f1 >= 0)
                app.TextAreaQuality.Value = [string(app.TextAreaQuality.Value); ...
                                            "f1(среднее) = " + string(mean(f1))];
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Figure = uifigure('Visible', 'off');
            app.Figure.Name = 'MATLAB App';
            app.Menu = uimenu(app.Figure);
            app.Menu.Text = 'Файл';
            app.Grid = uigridlayout(app.Figure);
            app.TabGroup = uitabgroup(app.Grid);
            app.TabResize = uitab(app.TabGroup);
            app.TabResize.Title = 'Изм. размера';
            app.TabSeg = uitab(app.TabGroup);
            app.TabSeg.Title = 'Сегментация';
            app.GridSeg = uigridlayout(app.TabSeg);
            app.AxesSeg = uiaxes(app.GridSeg);
            title(app.AxesSeg, 'Сегментированное изображение')
            app.AxesSeg.XLim = [0 1];
            app.AxesSeg.YLim = [0 1];
            app.AxesSeg.XTick = [0 1];
            app.AxesSeg.XTickLabel = '';
            app.AxesSeg.YTick = [0 1];
            app.AxesSeg.YTickLabel = '';
            app.AxesSeg.ButtonDownFcn = createCallbackFcn(app, @AxesSegButtonDown, true);
            colormap(app.AxesSeg, 'gray')
            app.AxesMap = uiaxes(app.GridSeg);
            title(app.AxesMap, 'Изображение карты')
            app.AxesMap.XLim = [0 1];
            app.AxesMap.YLim = [0 1];
            app.AxesMap.XTick = [0 1];
            app.AxesMap.XTickLabel = '';
            app.AxesMap.YTick = [0 1];
            app.AxesMap.YTickLabel = '';
            app.AxesMap.ButtonDownFcn = createCallbackFcn(app, @AxesMapButtonDown, true);
            app.GridSegPanel = uigridlayout(app.GridSeg);
            app.ButtonLoadMap = uibutton(app.GridSegPanel, 'push');
            app.ButtonLoadMap.ButtonPushedFcn = createCallbackFcn(app, @ButtonLoadMapPushed, true);
            app.ButtonLoadMap.Text = 'Загрузить файл карты';
            app.LabelLoadMap = uilabel(app.GridSegPanel);
            app.LabelLoadMap.Text = '';
            app.ButtonLoadModel = uibutton(app.GridSegPanel, 'push');
            app.ButtonLoadModel.ButtonPushedFcn = createCallbackFcn(app, @ButtonLoadModelPushed, true);
            app.ButtonLoadModel.Text = {'Выбрать модель'; 'для сегментации'};
            app.LabelLoadModel = uilabel(app.GridSegPanel);
            app.LabelLoadModel.Text = '';
            app.ButtonLoadLabel = uibutton(app.GridSegPanel, 'push');
            app.ButtonLoadLabel.ButtonPushedFcn = createCallbackFcn(app, @ButtonLoadLabelPushed, true);
            app.ButtonLoadLabel.Text = 'Загрузить файл разметки';
            app.LabelLoadLabel = uilabel(app.GridSegPanel);
            app.LabelLoadLabel.Text = '';
            app.ButtonStartSeg = uibutton(app.GridSegPanel, 'push');
            app.ButtonStartSeg.ButtonPushedFcn = createCallbackFcn(app, @ButtonStartSegPushed, true);
            app.ButtonStartSeg.Text = 'Выполнить сегменатцию';
            app.Label = uilabel(app.GridSegPanel);
            app.Label.Text = '';
            app.ListBoxChooseLayer = uilistbox(app.GridSegPanel);
            app.ListBoxChooseLayer.Items = {'Сегментированное изображение отсутствует'};
            app.ListBoxChooseLayer.ValueChangedFcn = createCallbackFcn(app, @ListBoxChooseLayerValueChanged, true);
            app.ListBoxChooseLayer.Value = 'Сегментированное изображение отсутствует';
            app.LabelChoose = uilabel(app.GridSegPanel);
            app.LabelChoose.Text = 'Выбрать сегментированное изображение:';
            app.ButtonLoadSeg = uibutton(app.GridSegPanel, 'push');
            app.ButtonLoadSeg.ButtonPushedFcn = createCallbackFcn(app, @ButtonLoadSegPushed, true);
            app.ButtonLoadSeg.Text = 'Загрузить сегментированное изображение';
            app.LabelLoadSeg = uilabel(app.GridSegPanel);
            app.LabelLoadSeg.Text = '';
            app.TextAreaQuality = uitextarea(app.GridSegPanel);
            app.TextAreaQuality.Editable = 'off';
            app.ButtonQuality = uibutton(app.GridSegPanel, 'push');
            app.ButtonQuality.ButtonPushedFcn = createCallbackFcn(app, @ButtonQualityPushed, true);
            app.ButtonQuality.Text = 'Расчёт критериев качества';
            app.ButtonSaveAll = uibutton(app.GridSegPanel, 'push');
            app.ButtonSaveAll.ButtonPushedFcn = createCallbackFcn(app, @ButtonSaveAllPushed, true);
            app.ButtonSaveAll.Text = 'Сохранить все слои в *.mat';
            app.ButtonSaveCur = uibutton(app.GridSegPanel, 'push');
            app.ButtonSaveCur.ButtonPushedFcn = createCallbackFcn(app, @ButtonSaveCurPushed, true);
            app.ButtonSaveCur.Text = 'Сохранить текущий слой в *.mat';
            app.ButtonSavePng = uibutton(app.GridSegPanel, 'push');
            app.ButtonSavePng.ButtonPushedFcn = createCallbackFcn(app, @ButtonSavePngPushed, true);
            app.ButtonSavePng.Text = 'Сохранить текущий слой в *.png';
            app.LabelTimeSeg = uilabel(app.GridSegPanel);
            app.LabelTimeSeg.Text = '';
            app.TabLoc = uitab(app.TabGroup);
            app.TabLoc.Title = 'Локализация';
            app.TabRec = uitab(app.TabGroup);
            app.TabRec.Title = 'Расп. ТО';
            app.TabRecLO = uitab(app.TabGroup);
            app.TabRecLO.Title = 'Расп. ЛО';
            app.TabRecSqO = uitab(app.TabGroup);
            app.TabRecSqO.Title = 'Расп. ПлО';
            app.TabComplex = uitab(app.TabGroup);
            app.TabComplex.Title = 'Комплексный анализ';
            app.TabDEM = uitab(app.TabGroup);
            app.TabDEM.Title = 'Интерполяция';
            % Show the figure after all components are created
            app.Figure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Figure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Figure)
        end
    end
end